classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SurrogateModelling              matlab.ui.Figure
        ModelKrigingandLOLAPanel_2      matlab.ui.container.Panel
        EvaluatesamplesButton_2         matlab.ui.control.Button
        NotevaluatedLabel_2             matlab.ui.control.Label
        StatusLabel_5                   matlab.ui.control.Label
        AddandEvaluatethenewpointinthemodelLabel  matlab.ui.control.Label
        KrigingButton_2                 matlab.ui.control.Button
        ReGenerateModelwithnewpointinsampleLabel  matlab.ui.control.Label
        NotgeneratedLabel_5             matlab.ui.control.Label
        StatusLabel_6                   matlab.ui.control.Label
        PredictButton_3                 matlab.ui.control.Button
        PredictedvalueforvectorLabel_2  matlab.ui.control.Label
        PredictedValueEditField_2       matlab.ui.control.NumericEditField
        FunctionevaluationnameEditField_2Label  matlab.ui.control.Label
        FunctionevaluationnameEditField_2  matlab.ui.control.EditField
        NewHyperparameterpredictionEditField_2Label  matlab.ui.control.Label
        NewHyperparameterpredictionEditField_2  matlab.ui.control.EditField
        NotpredictedLabel_3             matlab.ui.control.Label
        StatusLabel_10                  matlab.ui.control.Label
        VisualizeButton_2               matlab.ui.control.Button
        VisualizeoptionalDropDown_2Label  matlab.ui.control.Label
        VisualizeoptionalDropDown_2     matlab.ui.control.DropDown
        NumberofdimensionEditField_2Label  matlab.ui.control.Label
        NumberofdimensionEditField_2    matlab.ui.control.NumericEditField
        ModelKrigingandLOLAPanel        matlab.ui.container.Panel
        KrigingButton                   matlab.ui.control.Button
        GenerateModelLabel              matlab.ui.control.Label
        NotgeneratedLabel_2             matlab.ui.control.Label
        StatusLabel_3                   matlab.ui.control.Label
        NewHyperparameterpredictionEditFieldLabel  matlab.ui.control.Label
        NewHyperparameterpredictionEditField  matlab.ui.control.EditField
        PredictButton                   matlab.ui.control.Button
        PredictedvalueLabel             matlab.ui.control.Label
        PredictedValueEditField         matlab.ui.control.NumericEditField
        InitiateButton                  matlab.ui.control.Button
        LOLALabel                       matlab.ui.control.Label
        StatusLabel_4                   matlab.ui.control.Label
        GeneratenewpointButton          matlab.ui.control.Button
        PredictionPreviousPoint         matlab.ui.control.EditField
        PredictButton_2                 matlab.ui.control.Button
        PredictionofValueforpreviouspointLabel  matlab.ui.control.Label
        NotpredictedLabel               matlab.ui.control.Label
        StatusLabel_7                   matlab.ui.control.Label
        NewpointHyperparameterLabel     matlab.ui.control.Label
        NotpredictedLabel_2             matlab.ui.control.Label
        StatusLabel_9                   matlab.ui.control.Label
        PredictedvalueLabel_2           matlab.ui.control.Label
        NotinitiatedLabel               matlab.ui.control.Label
        NotgeneratedLabel_4             matlab.ui.control.Label
        StatusLabel_8                   matlab.ui.control.Label
        SampleandevaluationofsamplesPanel  matlab.ui.container.Panel
        HyperParamHigh                  matlab.ui.control.NumericEditField
        AddHyperParameterButton         matlab.ui.control.Button
        EvaluatesamplesButton           matlab.ui.control.Button
        NotevaluatedLabel               matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        GeneratesampleButton            matlab.ui.control.Button
        NotgeneratedLabel               matlab.ui.control.Label
        StatusLabel_2                   matlab.ui.control.Label
        UpperboundLabel                 matlab.ui.control.Label
        LowerboundLabel                 matlab.ui.control.Label
        HyperParamLowLabel              matlab.ui.control.Label
        HyperParamLow                   matlab.ui.control.NumericEditField
        ActiveHyperParameterListBoxLabel  matlab.ui.control.Label
        ActiveHyperParameterListBox     matlab.ui.control.ListBox
        FunctionevaluationnameEditFieldLabel  matlab.ui.control.Label
        FunctionevaluationnameEditField  matlab.ui.control.EditField
        NumberofsampleswantedEditFieldLabel  matlab.ui.control.Label
        NumberofsampleswantedEditField  matlab.ui.control.EditField
        NumberoflineaddedLabel          matlab.ui.control.Label
        numberofdimensionsLabel         matlab.ui.control.Label
        EvaluationofSampleLabel         matlab.ui.control.Label
        SampleGenerationLabel           matlab.ui.control.Label
        HyperParameterselectionLabel    matlab.ui.control.Label
        VisualizeoptionalDropDownLabel  matlab.ui.control.Label
        VisualizeoptionalDropDown       matlab.ui.control.DropDown
        VisualizeButton                 matlab.ui.control.Button
        NumberofdimensionEditFieldLabel  matlab.ui.control.Label
        NumberofdimensionEditField      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        samples % Sample Generated by LHC
        evaluatedSamples %sample evaluated by pca and sammon or other method
        doe %design of experiment object
        model %model
        seq %lola sequence
        points %points generated by LOLA
        evaluatedSamples2 %second evaluation
        model2 %model2
    end
    

    methods (Access = private)

        % Callback function
        function AddHyperParam(app, event)
            %get both hyperParam Value and concatenate them
            low = get(app.HyperParamLow,'Value');
            high = get(app.HyperParamHigh,'Value');
            lowstr = num2str(low);
            highstr = num2str(high);
            lowstr = strcat(lowstr,':');
            str = strcat(lowstr, highstr);
            
            %add values to listbox
            additemtolistbox( app.ActiveHyperParameterListBox, str);
            
            function h = additemtolistbox(h, newitem)
                % ADDITEMTOLISTBOX - add a new items to the listbox
                % H = ADDITEMTOLISTBOX(H, STRING)
                % H listbox handle
                % STRING a new item to display
                oldset = get(h, 'Items');
                oldset{end+1} = newitem;
                set(h, 'Items', oldset);
                
            end
        end

        % Callback function
        function DeleteHyperParam(app, event)
            %retrieve current list
            value = app.ActiveHyperParameterListBox.Value;
            choice = questdlg('You are about to delete an Hyper Parameter, proceed?', ...
               	'HyperParameter deletion', ...
               	'Yes','Cancel','cancel');
            
            % Handle response
            switch choice
                case 'Yes'
                    deleteitemtolistbox(app.ActiveHyperParameterListBox, value);
                    disp('ok');
                case 'Cancel'
                    disp('ok');
            end
            
            function h = deleteitemtolistbox(h, olditem)
                % ADDITEMTOLISTBOX - delete an items of the listbox
                % H = ADDITEMTOLISTBOX(H, STRING)
                % H listbox handle
                % STRING a new item to display
                oldset = get(h, 'Items');
                ind =find(ismember(oldset,olditem));
                oldset(ind)=[];
                set(h, 'Items', oldset);
            end
        end

        % Callback function
        function EvaluateSample(app, event)
            %get type of evaluation function
            type = get(app.FunctionevaluationnameEditField,'Value');
            
            %error check before execution
            if isempty(type)
                set(app.NotevaluatedLabel,'Text','An error occured unknown evaluation');
            elseif isempty(app.samples)
                set(app.NotevaluatedLabel,'Text','No samples generated yet');
            else
                %if function not defined in the code it will throw an error
                try
                    app.evaluatedSamples = EvaluatorHelper.EvaluateSamples(app.samples,type);
                    set(app.NotevaluatedLabel,'Text',strcat( 'Generated using',{' '},type));
                catch
                    set(app.NotevaluatedLabel,'Text','Error, unknown function');
                end
            end
            
        end

        % Callback function
        function GenerateSample(app, event)
            %retrieve current hyperParam
            hyperParam = get(app.ActiveHyperParameterListBox,'Items');
            if isempty(hyperParam  )
                set(app.NotgeneratedLabel,'Text','Error, you must imput at least one hyper parameter');
            else
                %fill the DesignOfExperiment for easy access later
                app.doe = DesignOfExperimentSettings();
                app.doe.UpperBound = [];
                app.doe.LowerBound = [];
                text = '[';
                
                for i=1:length(hyperParam)
                    params = strsplit( hyperParam{i},':');
                    app.doe.UpperBound(end+1) = str2num(params{1});
                    app.doe.LowerBound(end+1) = str2num(params{2});
                    text = strcat(text,'0,');
                end     
                
                text = text(1:end-1);
                text = strcat(text,']');
                set(app.NewHyperparameterpredictionEditField,'Value',text);
                set(app.NewHyperparameterpredictionEditField_2,'Value',text);
                
                %sample generation
                if isempty(get(app.NumberofsampleswantedEditField,'Value'))
                    set(app.NotgeneratedLabel,'Text','Error, You must imput a number of sample');
                else                    
                    app.samples = LHCHelper.GetSamplePoints(str2num(get(app.NumberofsampleswantedEditField,'Value')),app.doe.UpperBound,app.doe.LowerBound);
                    
                    if length(app.samples)>1
                        set(app.NotgeneratedLabel,'Text','Samples generated successfully');
                    else
                        set(app.NotgeneratedLabel,'Text','Error, sample not generated');
                    end
                end
                
                
            end
            
        end

        % Button pushed function: KrigingButton
        function GenerateModel(app, event)
            if isempty(app.evaluatedSamples)
                set(app.NotgeneratedLabel_2,'Text','Error, No evaluated samples generated yet');
            else
                app.model = ModelHelper.CreateGPModel(app.evaluatedSamples,[]);
                
                if isempty(app.model)
                    set(app.NotgeneratedLabel_2,'Text','Error, failed to generate model');
                else
                    set(app.NotgeneratedLabel_2,'Text','Model successfully generated');
                end
            end
            
        end

        % Button pushed function: PredictButton
        function PredictValue(app, event)
            vector = get(app.NewHyperparameterpredictionEditField,'Value');
            try
                newValue = ModelHelper.Predict(str2num(vector),app.model)
                set(app.PredictedValueEditField,'Value',newValue);
                set(app.NotpredictedLabel,'Text','Point predicted successfully');
            catch
                set(app.NotpredictedLabel,'Text','Error, failed to predict value, check hyper parameter');
            end
        end

        % Button pushed function: InitiateButton
        function LolaStart(app, event)
            try
                app.seq = LOLAHelper.SetupLOLA(app.evaluatedSamples,app.doe);
                set(app.NotinitiatedLabel,'Text','LOLA successfully generated');
                disp(app.seq);
            catch
                set(app.NotinitiatedLabel,'Text','Error, failed to generate LOLA');
            end
            
        end

        % Button pushed function: GeneratenewpointButton
        function LolaGenerate(app, event)
            % now we can generate some points
            try
                [app.seq,app.points] = LOLAHelper.GenerateNewPoint(app.seq);
                set(app.NotgeneratedLabel_4,'Text','Point generated');
                text = get(app.NewpointHyperparameterLabel,'Text');
                text = strcat(text,{' '},num2str(app.points));
                set(app.NewpointHyperparameterLabel,'Text',text);
            catch
                set(app.NotgeneratedLabel_4,'Text','Error, failed to generate new point');
            end
        end

        % Button pushed function: PredictButton_2
        function PredictPrevious(app, event)
            try
                val = ModelHelper.Predict(app.points,app.model);
                set(app.PredictionPreviousPoint,'Value',num2str(val));
                set(app.NotpredictedLabel_2,'Text','Point predicted successfully');
            catch
                set(app.NotpredictedLabel_2,'Text','Error, failed to generate new point');
            end
        end

        % Button pushed function: EvaluatesamplesButton_2
        function EvaluateNewSample(app, event)
            %get type of evaluation function
            type = get(app.FunctionevaluationnameEditField_2,'Value');
            
            %error check before execution
            if isempty(type)
                set(app.NotevaluatedLabel_2,'Text','An error occured unknown evaluation');
            elseif isempty(app.samples)
                set(app.NotevaluatedLabel_2,'Text','No samples generated yet');
            else
                %if function not defined in the code it will throw an error
                try
                    % now we can evaluate it and add it to the model
                    newSample = {Sample()};
                    newSample{end}.HyperParameters = app.points;
                    
                    % then evaluate it
                    app.evaluatedSamples2 = EvaluatorHelper.EvaluateSamples(newSample,type);
                    temp = app.evaluatedSamples2{end};
                    app.evaluatedSamples2 = app.evaluatedSamples;
                    app.evaluatedSamples2{end+1} = temp;
                    set(app.NotevaluatedLabel_2,'Text',strcat( 'Generated using',{' '},type));
                catch
                    set(app.NotevaluatedLabel_2,'Text','Error, unknown function');
                end
            end
            
        end

        % Button pushed function: KrigingButton_2
        function GenerateNewModel(app, event)
           
            
            if isempty(app.evaluatedSamples2)
                set(app.NotgeneratedLabel_5,'Text','Error, No evaluated samples generated yet');
            else                
                app.model2 = ModelHelper.CreateGPModel(app.evaluatedSamples2,[]);
                
                if isempty(app.model2)
                    set(app.NotgeneratedLabel_5,'Text','Error, failed to generate model');
                else
                    set(app.NotgeneratedLabel_5,'Text','Model successfully generated');
                end
            end
          
        end

        % Button pushed function: PredictButton_3
        function PredictNewModel(app, event)
             
            vector = get(app.NewHyperparameterpredictionEditField_2,'Value');
            try
                newValue = ModelHelper.Predict(str2num(vector),app.model2)
                set(app.PredictedValueEditField_2,'Value',newValue);
                set(app.NotpredictedLabel_3,'Text','Point predicted successfully');
            catch
                set(app.NotpredictedLabel_3,'Text','Error, failed to predict value, check hyper parameter');
            end
           
        end

        % Button pushed function: VisualizeButton
        function VisualizeFirstSample(app, event)
            type = get(app.VisualizeoptionalDropDown,'Value');
            nbrDim = get(app.NumberofdimensionEditField,'Value');            
            VisualisationHelper.CreatePlot(app.evaluatedSamples,nbrDim,lower(type));
        end

        % Button pushed function: VisualizeButton_2
        function VisualizeSecondSample(app, event)
            type = get(app.VisualizeoptionalDropDown_2,'Value');
            nbrDim = get(app.NumberofdimensionEditField_2,'Value');            
            VisualisationHelper.CreatePlot(app.evaluatedSamples2,nbrDim,lower(type));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SurrogateModelling = uifigure;
            app.SurrogateModelling.Name = 'Surrogate Modelling';
            setAutoResize(app, app.SurrogateModelling, true)
            app.ModelKrigingandLOLAPanel_2 = uipanel(app.SurrogateModelling);
            app.ModelKrigingandLOLAPanel_2.Title = 'Model, Kriging and LOLA';
            app.EvaluatesamplesButton_2 = uibutton(app.ModelKrigingandLOLAPanel_2, 'push');
            app.EvaluatesamplesButton_2.ButtonPushedFcn = createCallbackFcn(app, @EvaluateNewSample, true);
            app.EvaluatesamplesButton_2.Text = 'Evaluate samples';
            app.NotevaluatedLabel_2 = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.NotevaluatedLabel_2.Text = 'Not evaluated';
            app.StatusLabel_5 = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.StatusLabel_5.Text = 'Status';
            app.AddandEvaluatethenewpointinthemodelLabel = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.AddandEvaluatethenewpointinthemodelLabel.Text = 'Add and Evaluate the new point in the model';
            app.KrigingButton_2 = uibutton(app.ModelKrigingandLOLAPanel_2, 'push');
            app.KrigingButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateNewModel, true);
            app.KrigingButton_2.Text = 'Kriging';
            app.ReGenerateModelwithnewpointinsampleLabel = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.ReGenerateModelwithnewpointinsampleLabel.Text = 'Re Generate Model with new point in sample';
            app.NotgeneratedLabel_5 = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.NotgeneratedLabel_5.Text = 'Not generated';
            app.StatusLabel_6 = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.StatusLabel_6.Text = 'Status';
            app.PredictButton_3 = uibutton(app.ModelKrigingandLOLAPanel_2, 'push');
            app.PredictButton_3.ButtonPushedFcn = createCallbackFcn(app, @PredictNewModel, true);
            app.PredictButton_3.Text = 'Predict';
            app.PredictedvalueforvectorLabel_2 = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.PredictedvalueforvectorLabel_2.Text = 'Predicted value for vector :';
            app.PredictedValueEditField_2 = uieditfield(app.ModelKrigingandLOLAPanel_2, 'numeric');
            app.PredictedValueEditField_2.Enable = 'off';
            app.PredictedValueEditField_2.Editable = 'off';
            app.FunctionevaluationnameEditField_2Label = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.FunctionevaluationnameEditField_2Label.Text = 'Function evaluation name';
            app.FunctionevaluationnameEditField_2 = uieditfield(app.ModelKrigingandLOLAPanel_2, 'text');
            app.NewHyperparameterpredictionEditField_2Label = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.NewHyperparameterpredictionEditField_2Label.Text = 'New Hyper parameter prediction';
            app.NewHyperparameterpredictionEditField_2 = uieditfield(app.ModelKrigingandLOLAPanel_2, 'text');
            app.NewHyperparameterpredictionEditField_2.Value = '[0,0]';
            app.NotpredictedLabel_3 = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.NotpredictedLabel_3.Text = 'Not predicted';
            app.StatusLabel_10 = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.StatusLabel_10.Text = 'Status';
            app.VisualizeButton_2 = uibutton(app.ModelKrigingandLOLAPanel_2, 'push');
            app.VisualizeButton_2.ButtonPushedFcn = createCallbackFcn(app, @VisualizeSecondSample, true);
            app.VisualizeButton_2.Text = 'Visualize';
            app.VisualizeoptionalDropDown_2Label = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.VisualizeoptionalDropDown_2Label.Text = 'Visualize (optional)';
            app.VisualizeoptionalDropDown_2 = uidropdown(app.ModelKrigingandLOLAPanel_2);
            app.VisualizeoptionalDropDown_2.Items = {'PCA', 'Sammon'};
            app.VisualizeoptionalDropDown_2.Value = 'PCA';
            app.NumberofdimensionEditField_2Label = uilabel(app.ModelKrigingandLOLAPanel_2);
            app.NumberofdimensionEditField_2Label.Text = 'Number of dimension';
            app.NumberofdimensionEditField_2 = uieditfield(app.ModelKrigingandLOLAPanel_2, 'numeric');
            app.NumberofdimensionEditField_2.Limits = [2 3];
            app.NumberofdimensionEditField_2.RoundFractionalValues = 'on';
            app.NumberofdimensionEditField_2.Value = 2;
            app.ModelKrigingandLOLAPanel = uipanel(app.SurrogateModelling);
            app.ModelKrigingandLOLAPanel.Title = 'Model, Kriging and LOLA';
            app.KrigingButton = uibutton(app.ModelKrigingandLOLAPanel, 'push');
            app.KrigingButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateModel, true);
            app.KrigingButton.Text = 'Kriging';
            app.GenerateModelLabel = uilabel(app.ModelKrigingandLOLAPanel);
            app.GenerateModelLabel.Text = 'Generate Model';
            app.NotgeneratedLabel_2 = uilabel(app.ModelKrigingandLOLAPanel);
            app.NotgeneratedLabel_2.Text = 'Not generated';
            app.StatusLabel_3 = uilabel(app.ModelKrigingandLOLAPanel);
            app.StatusLabel_3.Text = 'Status';
            app.NewHyperparameterpredictionEditFieldLabel = uilabel(app.ModelKrigingandLOLAPanel);
            app.NewHyperparameterpredictionEditFieldLabel.Text = 'New Hyper parameter prediction';
            app.NewHyperparameterpredictionEditField = uieditfield(app.ModelKrigingandLOLAPanel, 'text');
            app.NewHyperparameterpredictionEditField.Value = '[0,0]';
            app.PredictButton = uibutton(app.ModelKrigingandLOLAPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictValue, true);
            app.PredictButton.Text = 'Predict';
            app.PredictedvalueLabel = uilabel(app.ModelKrigingandLOLAPanel);
            app.PredictedvalueLabel.Text = 'Predicted value :';
            app.PredictedValueEditField = uieditfield(app.ModelKrigingandLOLAPanel, 'numeric');
            app.PredictedValueEditField.Enable = 'off';
            app.PredictedValueEditField.Editable = 'off';
            app.InitiateButton = uibutton(app.ModelKrigingandLOLAPanel, 'push');
            app.InitiateButton.ButtonPushedFcn = createCallbackFcn(app, @LolaStart, true);
            app.InitiateButton.Text = 'Initiate';
            app.LOLALabel = uilabel(app.ModelKrigingandLOLAPanel);
            app.LOLALabel.Text = 'LOLA';
            app.StatusLabel_4 = uilabel(app.ModelKrigingandLOLAPanel);
            app.StatusLabel_4.Text = 'Status';
            app.GeneratenewpointButton = uibutton(app.ModelKrigingandLOLAPanel, 'push');
            app.GeneratenewpointButton.ButtonPushedFcn = createCallbackFcn(app, @LolaGenerate, true);
            app.GeneratenewpointButton.Text = 'Generate new point';
            app.PredictionPreviousPoint = uieditfield(app.ModelKrigingandLOLAPanel, 'text');
            app.PredictionPreviousPoint.Enable = 'off';
            app.PredictionPreviousPoint.Editable = 'off';
            app.PredictButton_2 = uibutton(app.ModelKrigingandLOLAPanel, 'push');
            app.PredictButton_2.ButtonPushedFcn = createCallbackFcn(app, @PredictPrevious, true);
            app.PredictButton_2.Text = 'Predict';
            app.PredictionofValueforpreviouspointLabel = uilabel(app.ModelKrigingandLOLAPanel);
            app.PredictionofValueforpreviouspointLabel.Text = 'Prediction of Value for previous point';
            app.NotpredictedLabel = uilabel(app.ModelKrigingandLOLAPanel);
            app.NotpredictedLabel.Text = 'Not predicted';
            app.StatusLabel_7 = uilabel(app.ModelKrigingandLOLAPanel);
            app.StatusLabel_7.Text = 'Status';
            app.NewpointHyperparameterLabel = uilabel(app.ModelKrigingandLOLAPanel);
            app.NewpointHyperparameterLabel.Text = 'New point Hyper parameter :';
            app.NotpredictedLabel_2 = uilabel(app.ModelKrigingandLOLAPanel);
            app.NotpredictedLabel_2.Text = 'Not predicted';
            app.StatusLabel_9 = uilabel(app.ModelKrigingandLOLAPanel);
            app.StatusLabel_9.Text = 'Status';
            app.PredictedvalueLabel_2 = uilabel(app.ModelKrigingandLOLAPanel);
            app.PredictedvalueLabel_2.Text = 'Predicted value :';
            app.NotinitiatedLabel = uilabel(app.ModelKrigingandLOLAPanel);
            app.NotinitiatedLabel.Text = 'Not initiated';
            app.NotgeneratedLabel_4 = uilabel(app.ModelKrigingandLOLAPanel);
            app.NotgeneratedLabel_4.Text = 'Not generated';
            app.StatusLabel_8 = uilabel(app.ModelKrigingandLOLAPanel);
            app.StatusLabel_8.Text = 'Status :';
            app.SampleandevaluationofsamplesPanel = uipanel(app.SurrogateModelling);
            app.SampleandevaluationofsamplesPanel.Title = 'Sample and evaluation of samples';
            app.HyperParamHigh = uieditfield(app.SampleandevaluationofsamplesPanel, 'numeric');
            app.AddHyperParameterButton = uibutton(app.SampleandevaluationofsamplesPanel, 'push');
            app.AddHyperParameterButton.ButtonPushedFcn = createCallbackFcn(app, @AddHyperParam, true);
            app.AddHyperParameterButton.Text = 'Add Hyper Parameter';
            app.EvaluatesamplesButton = uibutton(app.SampleandevaluationofsamplesPanel, 'push');
            app.EvaluatesamplesButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateSample, true);
            app.EvaluatesamplesButton.Text = 'Evaluate samples';
            app.NotevaluatedLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.NotevaluatedLabel.Text = 'Not evaluated';
            app.StatusLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.StatusLabel.Text = 'Status';
            app.GeneratesampleButton = uibutton(app.SampleandevaluationofsamplesPanel, 'push');
            app.GeneratesampleButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSample, true);
            app.GeneratesampleButton.Text = 'Generate sample';
            app.NotgeneratedLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.NotgeneratedLabel.Text = 'Not generated';
            app.StatusLabel_2 = uilabel(app.SampleandevaluationofsamplesPanel);
            app.StatusLabel_2.Text = 'Status';
            app.UpperboundLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.UpperboundLabel.Text = 'Upper bound';
            app.LowerboundLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.LowerboundLabel.Text = 'Lower bound';
            app.HyperParamLowLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.HyperParamLowLabel.Text = 'Hyper parameter range';
            app.HyperParamLow = uieditfield(app.SampleandevaluationofsamplesPanel, 'numeric');
            app.HyperParamLow.ValueDisplayFormat = '%.0f';
            app.ActiveHyperParameterListBoxLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.ActiveHyperParameterListBoxLabel.Text = 'Active Hyper Parameter';
            app.ActiveHyperParameterListBox = uilistbox(app.SampleandevaluationofsamplesPanel);
            app.ActiveHyperParameterListBox.Items = {};
            app.ActiveHyperParameterListBox.ValueChangedFcn = createCallbackFcn(app, @DeleteHyperParam, true);
            app.ActiveHyperParameterListBox.Value = {};
            app.FunctionevaluationnameEditFieldLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.FunctionevaluationnameEditFieldLabel.Text = 'Function evaluation name';
            app.FunctionevaluationnameEditField = uieditfield(app.SampleandevaluationofsamplesPanel, 'text');
            app.NumberofsampleswantedEditFieldLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.NumberofsampleswantedEditFieldLabel.Text = 'Number of samples wanted';
            app.NumberofsampleswantedEditField = uieditfield(app.SampleandevaluationofsamplesPanel, 'text');
            app.NumberoflineaddedLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.NumberoflineaddedLabel.Text = 'Number of line added ';
            app.numberofdimensionsLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.numberofdimensionsLabel.Text = '= number of dimensions';
            app.EvaluationofSampleLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.EvaluationofSampleLabel.Text = 'Evaluation of Sample';
            app.SampleGenerationLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.SampleGenerationLabel.Text = 'Sample Generation';
            app.HyperParameterselectionLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.HyperParameterselectionLabel.Text = 'Hyper Parameter selection';
            app.VisualizeoptionalDropDownLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.VisualizeoptionalDropDownLabel.Text = 'Visualize (optional)';
            app.VisualizeoptionalDropDown = uidropdown(app.SampleandevaluationofsamplesPanel);
            app.VisualizeoptionalDropDown.Items = {'PCA', 'Sammon'};
            app.VisualizeoptionalDropDown.Value = 'PCA';
            app.VisualizeButton = uibutton(app.SampleandevaluationofsamplesPanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeFirstSample, true);
            app.VisualizeButton.Text = 'Visualize';
            app.NumberofdimensionEditFieldLabel = uilabel(app.SampleandevaluationofsamplesPanel);
            app.NumberofdimensionEditFieldLabel.Text = 'Number of dimension';
            app.NumberofdimensionEditField = uieditfield(app.SampleandevaluationofsamplesPanel, 'numeric');
            app.NumberofdimensionEditField.Limits = [2 3];
            app.NumberofdimensionEditField.RoundFractionalValues = 'on';
            app.NumberofdimensionEditField.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SurrogateModelling)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SurrogateModelling)
        end
    end
end